classdef AraConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure          % UI Figure
        ButtonFolder  matlab.ui.control.Button  % folder selection
        ButtonConvert matlab.ui.control.Button  % convert files
        Label         matlab.ui.control.Label   % This tool allow you to co...
        Label2        matlab.ui.control.Label   % araConverter v2 - Geoffre...
        PanelConvert  matlab.ui.container.Panel % Convert files
        LampResult    matlab.ui.control.Lamp   
        Label3        matlab.ui.control.Label   %  ara files detected.
        PanelAbout    matlab.ui.container.Panel % About araConverter
        UIAxes        matlab.ui.control.UIAxes  % Title
        LabelFiles    matlab.ui.control.Label   % 0
        LabelFolder   matlab.ui.control.Label   % Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonFolder button pushed function
        function ButtonFolderSelection(app)
            global filesListing araFolder
            
            araFolder = uigetdir;
            if araFolder == 0
                return
            end
            
            set(findobj('Tag','LabelFolder'), 'String', araFolder)
            filesListing = ls(araFolder);
            
            nbrARAfiles = 0;
            for i = 1:size(filesListing, 1)
                k = strfind(filesListing(i,:),'.ARA');
                if size(k,1) > 0
                    nbrARAfiles = nbrARAfiles + 1;
                end
            end
            
            if nbrARAfiles > 0
                set(findobj('Tag','LabelFiles'), 'String', num2str(nbrARAfiles))
                set(findobj('Tag','ConvertButton'), 'Enable', 'on')
            else
                set(findobj('Tag','LabelFiles'), 'String', '0')
                set(findobj('Tag','ConvertButton'), 'Enable', 'off')
            
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.PanelConvert = uipanel(app.UIFigure);
            app.PanelConvert.Title = 'Convert files';
            app.PanelConvert.Units = 'pixels';
            app.ButtonFolder = uibutton(app.PanelConvert, 'push');
            app.ButtonFolder.ButtonPushedFcn = createCallbackFcn(app, @ButtonFolderSelection);
            app.ButtonFolder.Text = 'folder selection';
            app.ButtonConvert = uibutton(app.PanelConvert, 'push');
            app.ButtonConvert.Enable = 'off';
            app.ButtonConvert.Text = 'convert files';
            app.LampResult = uilamp(app.PanelConvert);
            app.LampResult.Enable = 'off';
            app.Label3 = uilabel(app.PanelConvert);
            app.Label3.Text = ' ara files detected.';
            app.LabelFiles = uilabel(app.PanelConvert);
            app.LabelFiles.Text = '0';
            app.LabelFolder = uilabel(app.PanelConvert);
            app.PanelAbout = uipanel(app.UIFigure);
            app.PanelAbout.Title = 'About araConverter';
            app.PanelAbout.Units = 'pixels';
            app.Label = uilabel(app.PanelAbout);
            app.Label.Text = {'This tool allow you to convert ARA raw files comming from the ASCTEC FLIR TAU 2 640. '; 'Output files are in tiff 16 bit. "Asctec flir Tau 2/640" metadata are in xml file.'};
            app.Label2 = uilabel(app.PanelAbout);
            app.Label2.Text = 'araConverter v2 - Geoffrey Vincent - 2016 © EPFL / ARCHEOTECH';
            app.UIAxes = uiaxes(app.PanelAbout);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = AraConverter()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end